classdef ProjektSynchrofazory < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        AkcjeMenu           matlab.ui.container.Menu
        DodajurzdzenieMenu  matlab.ui.container.Menu
        UsuurzdzenieMenu    matlab.ui.container.Menu
        MapaMenu            matlab.ui.container.Menu
        UstawieniaMenu      matlab.ui.container.Menu
        GridLayout          matlab.ui.container.GridLayout
        TabGroup            matlab.ui.container.TabGroup
        WykresykoloweTab    matlab.ui.container.Tab
        GridForPlots        matlab.ui.container.GridLayout
        WidokszczegowyTab   matlab.ui.container.Tab
        GridLayout2         matlab.ui.container.GridLayout
        UIAxes4             matlab.ui.control.UIAxes
        UIAxes3             matlab.ui.control.UIAxes
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        MapOfPlots
        DataProvider;
        RefreshRate=15;
    end
    
    properties (Access = private)
        OptionsDialog % Okno dialogowe dla zmiany ustawień
        PlotOptionsDialog
        AddDialog
        DeleteDialog
        DetailsDevice
        DeviceFrame
        DeviceDetailsDialog
        Timer % Used to refresh plots every x seconds
    end
   
    methods (Access = public,Static)
      
    end
    methods (Access = public)

        
        function UpdateOptions(app,refreshRate)
            app.RefreshRate=refreshRate;
            stop(app.Timer);
            app.Timer=app.InitTimer();
            start(app.Timer);
            delete(app.OptionsDialog);
        end
        
        function AddNewPMU(app,IP,ID,PORT)
          %TODO: Add new device to server  
           app.DataProvider.AddNewPMU(PMU(IP,PORT,ID,"CommunicationType",CommunicationTypes.SpontaneousUDP));
                       
           handlePlot=CustomPolarPlot(app.GridForPlots);
           handlePlot.SetHandle(app);
           handlePlot.SetTitle("ID:"+string(ID));
           handlePlot.ID=ID;
           app.MapOfPlots(ID)=PolarPlots(handlePlot);
           pause(1);
          delete(app.AddDialog);
        end
    
        function TimerCallback(app, obj, event)  
             %TODO: Update plots 
             if ~isempty(app.DeviceDetailsDialog)
                 app.DeviceDetailsDialog.UpdateValues(app.DataProvider.ListPMU(app.DeviceFrame));
             end
                k=keys(app.MapOfPlots);
                for i=1:size(app.MapOfPlots)
                   key=uint32(cell2mat(k(i)));
                   handleKey=app.DataProvider.ListPMU(key);
                    if ~isempty(handleKey) && ~isempty(handleKey.data) && ~isempty(app.MapOfPlots(key))
                        disp(handleKey.data.PHASORS1+"|"+handleKey.data.PHASORS0)
                        handle=app.MapOfPlots(key);
                        handle.UpdateGraph(handleKey,app.DataProvider.ListPMU);
                    end
                end
        end
        function t=InitTimer(app)
            t = timer;

            t.TimerFcn = @app.TimerCallback;

            t.Period = app.RefreshRate;

            t.TasksToExecute = Inf;
            t.ExecutionMode = 'fixedRate';
        end

        function PlotOptions(app,PlotID)
            app.PlotOptionsDialog = PolarPlotOptions(app,PlotID);
        end

        function PlotPlotOptionSelected(app)
            %TODO: Set polar plot to change
            delete(app.PlotOptionsDialog);
        end

        function DeviceDetails(app,PlotID)
            %TODO: Set device to details tab and remove others selection
        end

        function DeviceFrames(app,PlotID)
            delete(app.DeviceDetailsDialog)
            app.DeviceFrame=PlotID;
            app.DeviceDetailsDialog = DeviceDetailsDialog(app.DataProvider.ListPMU(PlotID));
        end

        function DeleteDevice(app,ID)
            stop(app.Timer);
            Id=uint32(ID);
            app.DataProvider.DeletePMU(Id);
            remove(app.MapOfPlots, Id);
            delete(app.DeleteDialog);
            [rows,~]=size(app.MapOfPlots);
            k=keys(app.MapOfPlots);
            row=1;
            column=1;
               for i=1:rows
                    key=uint32(cell2mat(k(i)));
                    handle = app.MapOfPlots(key);
                    handle.HandleToPlot.Layout.Row =row;
                    handle.HandleToPlot.Layout.Column =column;
                    column=column+1;
                    if column>4
                        column=1;
                        row=row+1;
                    end
               end
            start(app.Timer);
        end

        function LoadFiles(app)
            addpath("enums\");
            addpath("enums\command\");
            addpath("enums\frame\");
            addpath("enums\config\");
            addpath("enums\data\");
            addpath("models\");
            addpath("models\frame\");
            addpath("helpers\");
            addpath("dialogs\");
            addpath("repository\");
            addpath("controls\");
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LoadFiles();
            handle = ListOfPMU();
            app.MapOfPlots=containers.Map("KeyType",'uint32','ValueType','any');  
            app.DataProvider=handle;
            app.Timer=app.InitTimer();
            start(app.Timer);
        end

        % Menu selected function: MapaMenu
        function MapaMenuSelected(app, event)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.Timer);
            delete(app.Timer);
            delete(app.OptionsDialog);
            delete(app.AddDialog);
            delete(app.DeleteDialog);
            delete(app.PlotOptionsDialog);
            delete(app)
        end

        % Menu selected function: UstawieniaMenu
        function UstawieniaMenuSelected(app, event)
            app.OptionsDialog = UstawieniaDialog(app);
        end

        % Menu selected function: DodajurzdzenieMenu
        function DodajurzdzenieMenuSelected(app, event)
            if size(app.MapOfPlots)<=16
                app.AddDialog = DodajUrzadzenieDialog(app);
            end
        end

        % Menu selected function: UsuurzdzenieMenu
        function UsuurzdzenieMenuSelected(app, event)
            if size(app.MapOfPlots)>0
                app.DeleteDialog = UsunUrzadzenieDialog(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AkcjeMenu = uimenu(app.UIFigure);
            app.AkcjeMenu.Text = 'Akcje';
            app.DodajurzdzenieMenu = uimenu(app.AkcjeMenu);
            app.DodajurzdzenieMenu.MenuSelectedFcn = createCallbackFcn(app, @DodajurzdzenieMenuSelected, true);
            app.DodajurzdzenieMenu.Text = 'Dodaj urządzenie';
            app.UsuurzdzenieMenu = uimenu(app.AkcjeMenu);
            app.UsuurzdzenieMenu.MenuSelectedFcn = createCallbackFcn(app, @UsuurzdzenieMenuSelected, true);
            app.UsuurzdzenieMenu.Text = 'Usuń urządzenie';
            app.MapaMenu = uimenu(app.UIFigure);
            app.MapaMenu.MenuSelectedFcn = createCallbackFcn(app, @MapaMenuSelected, true);
            app.MapaMenu.Text = 'Mapa';
            app.UstawieniaMenu = uimenu(app.UIFigure);
            app.UstawieniaMenu.MenuSelectedFcn = createCallbackFcn(app, @UstawieniaMenuSelected, true);
            app.UstawieniaMenu.Text = 'Ustawienia';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.WykresykoloweTab = uitab(app.TabGroup);
            app.WykresykoloweTab.Title = 'Wykresy kołowe';
            app.GridForPlots = uigridlayout(app.WykresykoloweTab);
            app.WidokszczegowyTab = uitab(app.TabGroup);
            app.WidokszczegowyTab.Title = 'Widok szczegółowy';
            app.GridLayout2 = uigridlayout(app.WidokszczegowyTab);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout2);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout2);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjektSynchrofazory

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end